classdef manual_multisession_align < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                matlab.ui.Figure
        TemplateNumberLabel         matlab.ui.control.Label
        TemplateNumberSlider        matlab.ui.control.Slider
        LoadtemplateButton          matlab.ui.control.Button
        AddROIButton                matlab.ui.control.Button
        DeleteROIButton             matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        ROIsListBoxLabel            matlab.ui.control.Label
        ROIsListBox                 matlab.ui.control.ListBox
        AddonFrameButton            matlab.ui.control.Button
        DeleteonFrameButton         matlab.ui.control.Button
        DisplayallCheckBox          matlab.ui.control.CheckBox
        ROInumberCheckBox           matlab.ui.control.CheckBox
        LoadButton                  matlab.ui.control.Button
        ROIsizeEditFieldLabel       matlab.ui.control.Label
        ROIsizeEditField            matlab.ui.control.NumericEditField
        TransparencyEditFieldLabel  matlab.ui.control.Label
        TransparencyEditField       matlab.ui.control.NumericEditField
        LoadspatialcomponentButton  matlab.ui.control.Button
        DisplayspatialcomponentCheckBox  matlab.ui.control.CheckBox
        BrightnessMinSliderLabel    matlab.ui.control.Label
        BrightnessMinSlider         matlab.ui.control.Slider
        BrightnessMaxSliderLabel    matlab.ui.control.Label
        BrightnessMaxSlider         matlab.ui.control.Slider
        RegisterButton              matlab.ui.control.Button
        TemplateEditFieldLabel      matlab.ui.control.Label
        TemplateEditField           matlab.ui.control.NumericEditField
        RegisteralltemplatesbasedonROIsLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function [] = update_figure(app, data)
            
            imagesc(data.ax, data.template{data.day}); 
            dims = size(data.template{data.day});
            xlim(data.ax, [0 dims(2)]); ylim(data.ax, [0 dims(1)]);
            caxis(data.ax, data.clim{data.day});
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            hold(data.ax, 'on');
            
            if ~app.DisplayallCheckBox.Value
                if ~isnan(data.roi_idx) && ~isnan(data.roi_coord(data.roi_idx,data.day,1))
                    roi_cc = data.roi_cc(mod(data.roi_idx, size(data.roi_cc,1))+1, :);
                    scatter(data.ax, data.roi_coord(data.roi_idx,data.day,1), data.roi_coord(data.roi_idx,data.day,2), ...
                        data.mksz, roi_cc, 'filled', 'markeredgecolor', 'none', 'markerfacealpha', data.mkalpha);
                    if app.ROInumberCheckBox.Value
                        text(data.ax, data.roi_coord(data.roi_idx,data.day,1), data.roi_coord(data.roi_idx,data.day,2), ...
                            num2str(data.roi_idx), 'FontSize', 8, 'Color', [1 1 0]);
                    end
                end
            elseif app.DisplayallCheckBox.Value
                if ~all(isnan(data.roi_coord(:,data.day,1)))
                    roi_cc = data.roi_cc(mod(1:size(data.roi_coord,1), size(data.roi_cc,1))+1, :);
                    scatter(data.ax, data.roi_coord(:,data.day,1), data.roi_coord(:,data.day,2),...
                        data.mksz, roi_cc, 'filled', 'markeredgecolor', 'none', 'markerfacealpha', data.mkalpha);
                    if app.ROInumberCheckBox.Value
                        for roi_idx = 1:size(data.roi_coord,1)
                            if ~isnan(data.roi_coord(roi_idx,data.day,1))
                                text(data.ax, data.roi_coord(roi_idx,data.day,1), data.roi_coord(roi_idx,data.day,2), ...
                                    num2str(roi_idx), 'FontSize', 8, 'Color', [1 1 0]);
                            end
                        end
                    end
                end
            end
            
            if app.DisplayspatialcomponentCheckBox.Value
                if ~isempty(data.roi_contour_combined{data.day})
                    cc = [1 1 0.6];
                    scatter(data.ax, data.roi_contour_combined{data.day}(:,2), data.roi_contour_combined{data.day}(:,1), 0.1, cc);
%                       contour(data.ax2, data.roi_mask{data.day});
%                     imagesc(data.ax2, 1-data.roi_mask{data.day}, 'AlphaData', 0.1);
%                     colormap(data.ax2, 'hot'); caxis(data.ax2, [-3 1]);
%                     data.ax2.UserData = linkprop([data.ax, data.ax2],...
%                         {'Position','InnerPosition','DataAspectRatio','xtick','ytick', ...
%                         'ydir','xdir','xlim','ylim'});
%                     data.ax2.Visible = 'off';
                end
            end
            
            hold(data.ax, 'off');
            
        end
        
        function [data] = update_template(app, data, new_val)
            
            % round up
            app.TemplateNumberSlider.Value = round(new_val);
            
            % update data
            data.day = new_val;
            app.TemplateNumberSlider.Limits = [1 data.num_data];
            app.TemplateNumberSlider.MajorTicks = 1:data.num_data;
            
            % update brightness scale
            app.BrightnessMinSlider.Limits = [data.clim{data.day}(1) data.clim{data.day}(2)];
            app.BrightnessMaxSlider.Limits = [data.clim{data.day}(1) data.clim{data.day}(2)];
            app.BrightnessMinSlider.Value = data.clim{data.day}(1);
            app.BrightnessMaxSlider.Value = data.clim{data.day}(2);
            
            % update template plot
            hold(data.ax, 'off');
            imagesc(data.ax, data.template{new_val});
            hold(data.ax, 'on');
            dims = size(data.template{new_val});
            xlim(data.ax, [0 dims(2)]); ylim(data.ax, [0 dims(1)]);
            caxis(data.ax, data.clim{data.day});
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            hold(data.ax, 'off');
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = guidata(app.MainUIFigure);
            
%             pos = [0.0288    0.1025    0.7063    0.8027];
%             pos = [0.0300    0.1100    0.6956    0.8050];
%             ax = axes('Parent', app.MainUIFigure, 'position', pos);
%             set(ax, 'xtick', [], 'ytick', []);
%             set(ax, 'box', 'on');
%             set(ax ,'Layer', 'Top')
%             
%             data.ax = ax;
%             guidata(app.MainUIFigure, data);
            
        end

        % Button pushed function: LoadtemplateButton
        function LoadtemplateButtonPushed(app, event)
            
            [fname, fpath] = uigetfile({'*.mat','MAT-files'}, 'Select template file');
            if fname==0; return; end
            ld = load([fpath fname]);
            data = guidata(app.MainUIFigure);
            
            % update figure
%             pos = app.UIAxes.Position./app.MainUIFigure.Position([3,4,3,4]);
%             pos = [0.0126    0.1358    0.6918    0.8126];

            pos = [0.0300    0.1500    0.6956    0.7550];
            ax = axes('Parent', app.MainUIFigure, 'position', pos);
            set(ax, 'xtick', [], 'ytick', []);
            set(ax, 'box', 'on');
            set(ax ,'Layer', 'Top')
            
            data.ax = ax;
            data.template = ld.template;
            imagesc(data.ax, ld.template{1});
            hold(data.ax, 'on');
            dims = size(ld.template{1});
            xlim(data.ax, [0 dims(2)]); ylim(ax, [0 dims(1)]);
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            num_data = length(ld.template);
            data.num_data = num_data;
            hold(data.ax, 'off');
            
            % initialize
            data.roi_coord = nan(0,data.num_data,2);
            data.roi_cc = jet(50); 
            data.roi_cc = data.roi_cc(randperm(50),:);
            data.mksz = app.ROIsizeEditField.Value*10;
            data.mkalpha = app.TransparencyEditField.Value;
            data.roi_idx = NaN;
            data.clim = cell(1,num_data);
            data.roi_mask = cell(1,num_data);
            
            % remove all current ROIs
            app.ROIsListBox.Items = {};
            app.ROIsListBox.ItemsData = [];
            app.ROIsListBox.Value = {};
            
            % update slider
            app.TemplateNumberSlider.Limits = [1 num_data];
            app.TemplateNumberSlider.MajorTicks = 1:num_data;
            data.day = 1;
            for i = 1:data.num_data
                data.clim{i} = [min(data.template{i}(:)) max(data.template{i}(:))];
            end
            app.BrightnessMinSlider.Limits = [data.clim{1}(1) data.clim{1}(2)];
            app.BrightnessMaxSlider.Limits = [data.clim{1}(1) data.clim{1}(2)];
            app.BrightnessMinSlider.Value = data.clim{1}(1);
            app.BrightnessMaxSlider.Value = data.clim{1}(2);
            
            % set default template register number
            app.TemplateEditField.Value = 1;
            
            % store data
            guidata(app.MainUIFigure, data);
            
        end

        % Value changed function: TemplateNumberSlider
        function TemplateNumberSliderValueChanged(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            % round up
            new_val = round(app.TemplateNumberSlider.Value);
            data.day = new_val;
            
            app.BrightnessMinSlider.Limits = [min(data.template{data.day}(:)) max(data.template{data.day}(:))];
            app.BrightnessMaxSlider.Limits = [min(data.template{data.day}(:)) max(data.template{data.day}(:))];
            app.BrightnessMinSlider.Value = data.clim{data.day}(1);
            app.BrightnessMaxSlider.Value = data.clim{data.day}(2);
            
            % update template and roi
            update_template(app, data, new_val);
            update_figure(app, data);
            
            % save data
            guidata(app.MainUIFigure, data);
            
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            
            % add row in list
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            data.day = app.TemplateNumberSlider.Value;
            roi_list = app.ROIsListBox.Items;
            data.roi_idx = length(roi_list)+1;
            new_item = ['ROI' num2str(data.roi_idx)];
            app.ROIsListBox.Items = cat(2, roi_list, new_item);
            app.ROIsListBox.ItemsData(data.roi_idx) = data.roi_idx;
            
            data.roi_coord(data.roi_idx,:,:) = NaN;
            
            % make current selection default
            app.ROIsListBox.Value = data.roi_idx;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
            
        end

        % Callback function
        function UITableCellSelection(app, event)

            
        end

        % Button pushed function: AddonFrameButton
        function AddonFrameButtonPushed(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            % select from figure
            current_roi = drawcircle(data.ax, 'visible', 'off');
            try
                data.roi_coord(data.roi_idx,data.day,:) = current_roi.Position;
            catch ME     
            end
            update_figure(app, data);
            
            guidata(app.MainUIFigure, data);
            
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)
            
            % get current roi selection index
            data = guidata(app.MainUIFigure);
            data.roi_idx = app.ROIsListBox.Value;
            guidata(app.MainUIFigure, data);
            update_figure(app, data);
            
        end

        % Value changed function: DisplayallCheckBox
        function DisplayallCheckBoxValueChanged(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
            
        end

        % Value changed function: ROInumberCheckBox
        function ROInumberCheckBoxValueChanged(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
            
        end

        % Window scroll wheel function: MainUIFigure
        function MainUIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            new_val = app.TemplateNumberSlider.Value + round(verticalScrollCount);
            new_val = max(min(new_val, data.num_data), 1);
            data.day = new_val;
    
            % update template and roi
            update_template(app, data, new_val);
            update_figure(app, data);
            
            % save data
            guidata(app.MainUIFigure, data);
            
            
        end

        % Button pushed function: DeleteonFrameButton
        function DeleteonFrameButtonPushed(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            % select from figure
            data.roi_coord(data.roi_idx,data.day,:) = [NaN, NaN];
            update_figure(app, data);
            
            guidata(app.MainUIFigure, data);
            
        end

        % Button pushed function: DeleteROIButton
        function DeleteROIButtonPushed(app, event)
            % delete row in list
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            data.day = app.TemplateNumberSlider.Value;
            roi_to_remove = data.roi_idx;
            keep_idx = setdiff(1:length(app.ROIsListBox.Items), roi_to_remove);
            app.ROIsListBox.Items = app.ROIsListBox.Items(keep_idx);
            app.ROIsListBox.ItemsData = app.ROIsListBox.ItemsData(keep_idx);
            data.roi_coord = data.roi_coord(keep_idx,:,:);
            
            data.roi_idx = max(1, roi_to_remove-1);
            
            % make current selection default
            app.ROIsListBox.Value = data.roi_idx;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
            
        end

        % Key press function: MainUIFigure
        function MainUIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'a'
                    AddonFrameButtonPushed(app, event);
                case 'd'
                    DeleteonFrameButtonPushed(app, event);
            end
        end

        % Value changed function: ROIsizeEditField
        function ROIsizeEditFieldValueChanged(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            data.mksz = app.ROIsizeEditField.Value*10;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
            
        end

        % Value changed function: TransparencyEditField
        function TransparencyEditFieldValueChanged(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            data.mkalpha = app.TransparencyEditField.Value;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            roi_coord = data.roi_coord;
            
            [fname, fpath] = uiputfile({'*.mat','MAT-files'}, 'Save ROI result');
            if fname==0; return; end
            save([fpath fname], 'roi_coord', '-v7.3');
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            [fname, fpath] = uigetfile({'*.mat','MAT-files'}, 'Load ROI result');
            if fname==0; return; end
            ld = load([fpath fname]);
            
            if ~isfield(ld, 'roi_coord')
                uialert(app.MainUIFigure,'Invalid file content','Invalid File');
            end
            data.roi_coord = ld.roi_coord;
            
            roi_str = cell(1,size(data.roi_coord,1));
            for i = 1:size(data.roi_coord,1)
                roi_str{i} = ['ROI' num2str(i)];
            end
            app.ROIsListBox.Items = roi_str;
            app.ROIsListBox.ItemsData = 1:size(data.roi_coord,1);
            data.roi_idx = 1;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
            
        end

        % Callback function
        function LoadspatialcomponentButtonPushed(app, event)
            
        end

        % Button pushed function: LoadspatialcomponentButton
        function LoadspatialcomponentButtonPushed2(app, event)
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            [fname, fpath] = uigetfile({'*.mat','MAT-files'}, 'Load ROI spatial component');
            if fname==0; return; end
            ld = load([fpath fname]);
            
            if ~isfield(ld, 'ROI_mask')
                uialert(app.MainUIFigure,'Invalid file content','Invalid File');
            end
%             data.roi_mask = ld.ROI_mask;
            
%             data.ax2 = copyobj(data.ax, app.MainUIFigure);

            roi_center = cell(1,data.num_data);
            roi_contour = cell(1,data.num_data);
            roi_contour_combined = cell(1,data.num_data);
            for i = 1:data.num_data
%                 roi_contour{i} = cell2mat(bwboundaries(full(data.roi_mask{i}~=0)));
                roi_contour{i} = ld.ROI_mask{i}.cont;
                roi_center{i} = ld.ROI_mask{i}.cent;
                roi_contour_combined{i} = cell2mat(roi_contour{i}');
                % downsample
                roi_contour_combined{i} = roi_contour_combined{i}(2:2:end,:);
            end
            data.roi_contour = roi_contour;
            data.roi_center = roi_center;
            data.roi_contour_combined = roi_contour_combined;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
        end

        % Value changed function: DisplayspatialcomponentCheckBox
        function DisplayspatialcomponentCheckBoxValueChanged(app, event)
           
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
            
        end

        % Value changed function: BrightnessMinSlider
        function BrightnessMinSliderValueChanged(app, event)
            value = app.BrightnessMinSlider.Value;
            data = guidata(app.MainUIFigure);
            data.clim{data.day}(1) = value;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
        end

        % Value changed function: BrightnessMaxSlider
        function BrightnessMaxSliderValueChanged(app, event)
            value = app.BrightnessMaxSlider.Value;
            data = guidata(app.MainUIFigure);
            data.clim{data.day}(2) = value;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            
            data = guidata(app.MainUIFigure);
            idx_fixed = app.TemplateEditField.Value;
            if idx_fixed<1 || idx_fixed>data.num_data
                uialert(app.MainUIFigure, 'Value error', 'Invalid template number');
                return;
            end
            
            points_ref = squeeze(data.roi_coord(:,idx_fixed,:));
            ref = imref2d(size(data.template{idx_fixed}));
            data.template_reg = cell(1, data.num_data);
            data.roi_contour_reg = cell(1, data.num_data);
            data.roi_center_reg = cell(1, data.num_data);
            for n = 1:data.num_data
                points_mov = squeeze(data.roi_coord(:,n,:));
                good_idx = all(~isnan(points_ref),2) & all(~isnan(points_mov),2);
            
                % registration
                tform = fitgeotrans(points_mov(good_idx,:), points_ref(good_idx,:),'projective');
            
                % transform template
                data.template_reg{n} = imwarp(data.template{n}, tform, 'OutputView', ref);
            
                % transform spatial masks
                if isempty(data.roi_contour{n}); continue; end
                K = length(data.roi_contour{n});
                for i = 1:K
                    [yy,xx] = transformPointsForward(tform, data.roi_contour{n}{i}(:,2), data.roi_contour{n}{i}(:,1));
                    data.roi_contour_reg{n}{i} = [xx, yy];
                    [yy,xx] = transformPointsForward(tform, data.roi_center{n}(i,2), data.roi_center{n}(i,1));
                    data.roi_center_reg{n}(i,:) = [xx,yy];
                end
                data.roi_center_reg{n} = transformPointsForward(tform, data.roi_center{n});
            end
            
            guidata(app.MainUIFigure, data);
%             setappdata(app.MainMainUIFIgure, data);
            view_multisession_align;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure;
            app.MainUIFigure.Name = 'Main';
            app.MainUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @MainUIFigureWindowScrollWheel, true);
            app.MainUIFigure.KeyPressFcn = createCallbackFcn(app, @MainUIFigureKeyPress, true);
            app.TemplateNumberLabel = uilabel(app.MainUIFigure);
            app.TemplateNumberLabel.Text = {'Template'; 'Number'};
            app.TemplateNumberSlider = uislider(app.MainUIFigure);
            app.TemplateNumberSlider.MajorTicks = [];
            app.TemplateNumberSlider.ValueChangedFcn = createCallbackFcn(app, @TemplateNumberSliderValueChanged, true);
            app.TemplateNumberSlider.MinorTicks = [];
            app.LoadtemplateButton = uibutton(app.MainUIFigure, 'push');
            app.LoadtemplateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtemplateButtonPushed, true);
            app.LoadtemplateButton.Text = 'Load template';
            app.AddROIButton = uibutton(app.MainUIFigure, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Text = 'Add ROI';
            app.DeleteROIButton = uibutton(app.MainUIFigure, 'push');
            app.DeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIButtonPushed, true);
            app.DeleteROIButton.Text = 'Delete ROI';
            app.SaveButton = uibutton(app.MainUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ROIsListBoxLabel = uilabel(app.MainUIFigure);
            app.ROIsListBoxLabel.Text = 'ROIs';
            app.ROIsListBox = uilistbox(app.MainUIFigure);
            app.ROIsListBox.Items = {};
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Value = {};
            app.AddonFrameButton = uibutton(app.MainUIFigure, 'push');
            app.AddonFrameButton.ButtonPushedFcn = createCallbackFcn(app, @AddonFrameButtonPushed, true);
            app.AddonFrameButton.Text = 'Add on Frame';
            app.DeleteonFrameButton = uibutton(app.MainUIFigure, 'push');
            app.DeleteonFrameButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteonFrameButtonPushed, true);
            app.DeleteonFrameButton.Text = 'Delete on Frame';
            app.DisplayallCheckBox = uicheckbox(app.MainUIFigure);
            app.DisplayallCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayallCheckBoxValueChanged, true);
            app.DisplayallCheckBox.Text = 'Display all';
            app.ROInumberCheckBox = uicheckbox(app.MainUIFigure);
            app.ROInumberCheckBox.ValueChangedFcn = createCallbackFcn(app, @ROInumberCheckBoxValueChanged, true);
            app.ROInumberCheckBox.Text = 'ROI number';
            app.LoadButton = uibutton(app.MainUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ROIsizeEditFieldLabel = uilabel(app.MainUIFigure);
            app.ROIsizeEditFieldLabel.Text = 'ROI size';
            app.ROIsizeEditField = uieditfield(app.MainUIFigure, 'numeric');
            app.ROIsizeEditField.ValueChangedFcn = createCallbackFcn(app, @ROIsizeEditFieldValueChanged, true);
            app.ROIsizeEditField.Value = 10;
            app.TransparencyEditFieldLabel = uilabel(app.MainUIFigure);
            app.TransparencyEditFieldLabel.Text = 'Transparency';
            app.TransparencyEditField = uieditfield(app.MainUIFigure, 'numeric');
            app.TransparencyEditField.ValueChangedFcn = createCallbackFcn(app, @TransparencyEditFieldValueChanged, true);
            app.TransparencyEditField.Value = 0.4;
            app.LoadspatialcomponentButton = uibutton(app.MainUIFigure, 'push');
            app.LoadspatialcomponentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadspatialcomponentButtonPushed2, true);
            app.LoadspatialcomponentButton.Text = 'Load spatial component';
            app.DisplayspatialcomponentCheckBox = uicheckbox(app.MainUIFigure);
            app.DisplayspatialcomponentCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayspatialcomponentCheckBoxValueChanged, true);
            app.DisplayspatialcomponentCheckBox.Text = 'Display spatial component';
            app.BrightnessMinSliderLabel = uilabel(app.MainUIFigure);
            app.BrightnessMinSliderLabel.Text = 'Brightness Min';
            app.BrightnessMinSlider = uislider(app.MainUIFigure);
            app.BrightnessMinSlider.MajorTicks = [];
            app.BrightnessMinSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessMinSliderValueChanged, true);
            app.BrightnessMinSlider.MinorTicks = [];
            app.BrightnessMaxSliderLabel = uilabel(app.MainUIFigure);
            app.BrightnessMaxSliderLabel.Text = 'Brightness Max';
            app.BrightnessMaxSlider = uislider(app.MainUIFigure);
            app.BrightnessMaxSlider.MajorTicks = [];
            app.BrightnessMaxSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessMaxSliderValueChanged, true);
            app.BrightnessMaxSlider.MinorTicks = [];
            app.RegisterButton = uibutton(app.MainUIFigure, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'Register';
            app.TemplateEditFieldLabel = uilabel(app.MainUIFigure);
            app.TemplateEditFieldLabel.Text = 'Template';
            app.TemplateEditField = uieditfield(app.MainUIFigure, 'numeric');
            app.RegisteralltemplatesbasedonROIsLabel = uilabel(app.MainUIFigure);
            app.RegisteralltemplatesbasedonROIsLabel.Text = 'Register all templates based on ROIs';
        end
    end

    methods (Access = public)

        % Construct app
        function app = manual_multisession_align

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end