classdef view_multisession_align < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ViewresultsUIFigure            matlab.ui.Figure
        ROIsListBoxLabel               matlab.ui.control.Label
        ROIsListBox                    matlab.ui.control.ListBox
        SaveButton                     matlab.ui.control.Button
        DisplayallCheckBox             matlab.ui.control.CheckBox
        ROInumberCheckBox              matlab.ui.control.CheckBox
        ROIsizeEditFieldLabel          matlab.ui.control.Label
        ROIsizeEditField               matlab.ui.control.NumericEditField
        TransparencyEditFieldLabel     matlab.ui.control.Label
        TransparencyEditField          matlab.ui.control.NumericEditField
        DisplayspatialcomponentCheckBox  matlab.ui.control.CheckBox
        MatchROIsButton                matlab.ui.control.Button
        TemplateNumberSliderLabel      matlab.ui.control.Label
        TemplateNumberSlider           matlab.ui.control.Slider
        BrightnessMaxSliderLabel       matlab.ui.control.Label
        BrightnessMaxSlider            matlab.ui.control.Slider
        BrightnessMinSliderLabel       matlab.ui.control.Label
        BrightnessMinSlider            matlab.ui.control.Slider
        MaxdistEditFieldLabel          matlab.ui.control.Label
        MaxdistEditField               matlab.ui.control.NumericEditField
        ShowmindaysEditFieldLabel      matlab.ui.control.Label
        ShowmindaysEditField           matlab.ui.control.NumericEditField
        ShowmaxdaysEditFieldLabel      matlab.ui.control.Label
        ShowmaxdaysEditField           matlab.ui.control.NumericEditField
        MatchedROIdisplaysettingLabel  matlab.ui.control.Label
        RegistrationresultLabel        matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function [] = update_figure(app, data)
            
            imagesc(data.ax, data.template{data.day}); 
            dims = size(data.template{data.day});
            xlim(data.ax, [0 dims(2)]); ylim(data.ax, [0 dims(1)]);
            caxis(data.ax, data.clim{data.day});
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            hold(data.ax, 'on');
            
            if ~app.DisplayallCheckBox.Value
                current_spatial_idx = data.roi_list(data.show_idx(data.roi_idx), data.day);
                if ~isnan(data.roi_idx) && ~isnan(current_spatial_idx)
                    roi_cc = data.roi_cc(mod(data.roi_idx, size(data.roi_cc,1))+1, :);
                    scatter(data.ax, data.roi_center{data.day}(current_spatial_idx,1), data.roi_center{data.day}(current_spatial_idx,2), ...
                        data.mksz, roi_cc, 'filled', 'markeredgecolor', 'none', 'markerfacealpha', data.mkalpha);
                    if app.ROInumberCheckBox.Value
                        text(data.ax, data.roi_center{data.day}(current_spatial_idx,1), data.roi_center{data.day}(current_spatial_idx,2), ...
                            num2str(data.roi_idx), 'FontSize', 8, 'Color', [1 1 0]);
                    end
                end
            elseif app.DisplayallCheckBox.Value
                if ~all(isnan(data.roi_list(data.show_idx, data.day)))
                    roi_cc = data.roi_cc(mod(1:length(data.show_idx), size(data.roi_cc,1))+1, :);
                    current_spatial_idx = data.roi_list(data.show_idx, data.day);
                    valid_idx = ~isnan(current_spatial_idx);
                    roi_coord = data.roi_center{data.day}(current_spatial_idx(valid_idx),:);
                    roi_cc = roi_cc(valid_idx,:);
                    scatter(data.ax, roi_coord(:,1), roi_coord(:,2), data.mksz, roi_cc, 'filled', ...
                        'markeredgecolor', 'none', 'markerfacealpha', data.mkalpha);
                    if app.ROInumberCheckBox.Value
                        for roi_idx = 1:length(data.show_idx)
                            current_spatial_idx = data.roi_list(data.show_idx(roi_idx), data.day);
                            if ~isnan(current_spatial_idx)
                                text(data.ax, data.roi_center{data.day}(current_spatial_idx,1), ...
                                    data.roi_center{data.day}(current_spatial_idx,2), ...
                                    num2str(roi_idx), 'FontSize', 8, 'Color', [1 1 0]);
                            end
                        end
                    end
                end
            end
            
            if app.DisplayspatialcomponentCheckBox.Value
                if ~isempty(data.roi_contour_combined{data.day})
                    cc = [1 1 0.6];
                    scatter(data.ax, data.roi_contour_combined{data.day}(:,2), data.roi_contour_combined{data.day}(:,1), 0.1, cc);
                end
            end
            
            hold(data.ax, 'off');
            
        end
        
        function [data] = update_template(app, data, new_val)
            
            % round up
            app.TemplateNumberSlider.Value = round(new_val);
            
            % update data
            data.day = new_val;
            app.TemplateNumberSlider.Limits = [1 data.num_data];
            app.TemplateNumberSlider.MajorTicks = 1:data.num_data;
            
            % update brightness scale
            app.BrightnessMinSlider.Limits = [data.clim{data.day}(1) data.clim{data.day}(2)];
            app.BrightnessMaxSlider.Limits = [data.clim{data.day}(1) data.clim{data.day}(2)];
            app.BrightnessMinSlider.Value = data.clim{data.day}(1);
            app.BrightnessMaxSlider.Value = data.clim{data.day}(2);
            
            % update template plot
            hold(data.ax, 'off');
            imagesc(data.ax, data.template{new_val});
            hold(data.ax, 'on');
            dims = size(data.template{new_val});
            xlim(data.ax, [0 dims(2)]); ylim(data.ax, [0 dims(1)]);
            caxis(data.ax, data.clim{data.day});
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            hold(data.ax, 'off');
            
        end
        
        function [data] = update_roilist(app, data)
            data.num_show = length(data.show_idx);
            roi_str = cell(1,data.num_show);
            for i = 1:data.num_show
                roi_str{i} = ['ROI' num2str(i)];
            end
            app.ROIsListBox.Items = roi_str;
            app.ROIsListBox.ItemsData = 1:data.num_show;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            figpos_default = [680,84,714,522];
            
            pos = [0.0300    0.1500    0.6956    0.7550];
            ax = axes('Parent', app.ViewresultsUIFigure, 'position', pos);
            set(ax, 'xtick', [], 'ytick', []);
            set(ax, 'box', 'on');
            set(ax ,'Layer', 'Top')
            
            % find the root app handle
            figure_handle = findall(groot, 'Type', 'Figure');
            for i = 1:length(figure_handle)
                if strcmp(figure_handle(i).Name, 'Main')
                    idx = i;
                end
            end
            figure_handle = figure_handle(idx);
            data = guidata(figure_handle);
            pos = get(figure_handle, 'position');
            set(app.ViewresultsUIFigure, 'position', [pos(1)+20, pos(2)-20, pos(3), pos(4)]);
            handles = findobj(figure_handle, 'Type', 'uilistbox');
            set(app.ROIsListBox, 'position', handles.Position);
            handles = findobj(app.ViewresultsUIFigure, 'Type', 'uilabel');
            for i = 1:length(handles)
                if strcmp(handles(i).Text, 'Registration result')
                    new_pos = handles(i).Position;
                    new_pos(2) = new_pos(2)/figpos_default(4)*pos(4);
                    set(handles(i), 'position', new_pos);
                end
            end
            
            % rename variables for convenience
            data.template = data.template_reg;
            data.roi_center = data.roi_center_reg;
            data.roi_contour = data.roi_contour_reg;
            data.roi_contour_combined = cell(1,data.num_data);
            for i = 1:data.num_data
                data.roi_contour_combined{i} = cell2mat(data.roi_contour_reg{i}');
            end
            
            % show first template
            data.ax = ax;
            imagesc(data.ax, data.template{1});
            hold(data.ax, 'on');
            dims = size(data.template{1});
            xlim(data.ax, [0 dims(2)]); ylim(ax, [0 dims(1)]);
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            num_data = length(data.template);
            data.num_data = num_data;
            hold(data.ax, 'off');
            
            % initialize
            data.roi_cc = jet(50); 
            data.roi_cc = data.roi_cc(randperm(50),:);
            data.mksz = app.ROIsizeEditField.Value*10;
            data.mkalpha = app.TransparencyEditField.Value;
            data.roi_coord = [];
            data.roi_idx = NaN;
            data.num_found = NaN;

            % update slider
            app.TemplateNumberSlider.Limits = [1 num_data];
            app.TemplateNumberSlider.MajorTicks = 1:num_data;
            data.day = 1;
            app.BrightnessMinSlider.Limits = [data.clim{1}(1) data.clim{1}(2)];
            app.BrightnessMaxSlider.Limits = [data.clim{1}(1) data.clim{1}(2)];
            app.BrightnessMinSlider.Value = data.clim{1}(1);
            app.BrightnessMaxSlider.Value = data.clim{1}(2);
            
            % update show min max field
            app.ShowmindaysEditField.Value = 1;
            app.ShowmaxdaysEditField.Value = data.num_data;
            
            % store data
            guidata(app.ViewresultsUIFigure, data);
        end

        % Button pushed function: MatchROIsButton
        function MatchROIsButtonPushed(app, event)
            data = guidata(app.ViewresultsUIFigure);
            
            data.roi_list = nan(0, data.num_data);
            data.assigned_flag = cell(1, data.num_data);
            for n = 1:data.num_data
                data.assigned_flag{n} = false(size(data.roi_center{n},1), 1);
            end
            
            max_dist = app.MaxdistEditField.Value;
            for n = 1:data.num_data
                for i = 1:size(data.roi_center{n},1)
                    if data.assigned_flag{n}(i)==0
                        data.roi_list(end+1,:) = nan(1, data.num_data);
                        data.roi_list(end, n) = i;
                        % find the same roi in the following days
                        for m = n+1:data.num_data
                            d = pdist2(data.roi_center{n}(i,:), data.roi_center{m});
                            idx = find(d<max_dist);
                            if ~isempty(idx)
                                [~,best_idx] = min(d(idx));
                                data.roi_list(end,m) = idx(best_idx);
                                data.assigned_flag{m}(idx(best_idx)) = 1;
                            end
                        end
                    end
                end
            end
            
            data.found_flag = ~isnan(data.roi_list);
            data.num_found = sum(data.found_flag,2);
            data.show_idx = find(data.num_found>=app.ShowmindaysEditField.Value ...
                & data.num_found<=app.ShowmaxdaysEditField.Value);
            data.roi_idx = 1;
            
            % update ROI list box and figure
            data = update_roilist(app, data);
            update_figure(app, data);
            
            guidata(app.ViewresultsUIFigure, data);
            
        end

        % Window scroll wheel function: ViewresultsUIFigure
        function ViewresultsUIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            
            new_val = app.TemplateNumberSlider.Value + round(verticalScrollCount);
            new_val = max(min(new_val, data.num_data), 1);
            data.day = new_val;
    
            % update template and roi
            update_template(app, data, new_val);
            update_figure(app, data);
            
            % save data
            guidata(app.ViewresultsUIFigure, data);
            
        end

        % Value changed function: BrightnessMinSlider
        function BrightnessMinSliderValueChanged(app, event)
            value = app.BrightnessMinSlider.Value;
            data = guidata(app.ViewresultsUIFigure);
            data.clim{data.day}(1) = value;
            
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: BrightnessMaxSlider
        function BrightnessMaxSliderValueChanged(app, event)
            value = app.BrightnessMaxSlider.Value;
            data = guidata(app.ViewresultsUIFigure);
            data.clim{data.day}(2) = value;
            
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: TemplateNumberSlider
        function TemplateNumberSliderValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            
            % round up
            new_val = round(app.TemplateNumberSlider.Value);
            data.day = new_val;
            
            app.BrightnessMinSlider.Limits = [min(data.template{data.day}(:)) max(data.template{data.day}(:))];
            app.BrightnessMaxSlider.Limits = [min(data.template{data.day}(:)) max(data.template{data.day}(:))];
            app.BrightnessMinSlider.Value = data.clim{data.day}(1);
            app.BrightnessMaxSlider.Value = data.clim{data.day}(2);
            
            % update template and roi
            update_template(app, data, new_val);
            update_figure(app, data);
            
            % save data
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: DisplayallCheckBox
        function DisplayallCheckBoxValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
        end

        % Value changed function: ROInumberCheckBox
        function ROInumberCheckBoxValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
        end

        % Value changed function: DisplayspatialcomponentCheckBox
        function DisplayspatialcomponentCheckBoxValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
        end

        % Value changed function: ShowmindaysEditField
        function ShowmindaysEditFieldValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isnan(data.num_found); return; end
            data.show_idx = find(data.num_found>=app.ShowmindaysEditField.Value ...
                & data.num_found<=app.ShowmaxdaysEditField.Value);
            
            update_roilist(app, data);
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: ShowmaxdaysEditField
        function ShowmaxdaysEditFieldValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isnan(data.num_found); return; end
            data.show_idx = find(data.num_found>=app.ShowmindaysEditField.Value ...
                & data.num_found<=app.ShowmaxdaysEditField.Value);
            
            update_roilist(app, data);
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: ROIsizeEditField
        function ROIsizeEditFieldValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            
            data.mksz = app.ROIsizeEditField.Value*10;
            
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: TransparencyEditField
        function TransparencyEditFieldValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            
            data.mkalpha = app.TransparencyEditField.Value;
            
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)
            % get current roi selection index
            data = guidata(app.ViewresultsUIFigure);
            data.roi_idx = app.ROIsListBox.Value;
            guidata(app.ViewresultsUIFigure, data);
            update_figure(app, data);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            
            roi_list = data.roi_list;
            template = data.template;
            roi_center = data.roi_center;
            roi_contour = data.roi_contour;
            
            [fname, fpath] = uiputfile({'*.mat','MAT-files'}, 'Save registered ROI result');
            if fname==0; return; end
            save([fpath fname], 'roi_list', 'template', 'roi_center', 'roi_contour', '-v7.3');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ViewresultsUIFigure = uifigure;
            app.ViewresultsUIFigure.Name = 'View results';
            app.ViewresultsUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ViewresultsUIFigureWindowScrollWheel, true);
            app.ROIsListBoxLabel = uilabel(app.ViewresultsUIFigure);
            app.ROIsListBoxLabel.Text = 'ROIs';
            app.ROIsListBox = uilistbox(app.ViewresultsUIFigure);
            app.ROIsListBox.Items = {};
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Value = {};
            app.SaveButton = uibutton(app.ViewresultsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DisplayallCheckBox = uicheckbox(app.ViewresultsUIFigure);
            app.DisplayallCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayallCheckBoxValueChanged, true);
            app.DisplayallCheckBox.Text = 'Display all';
            app.ROInumberCheckBox = uicheckbox(app.ViewresultsUIFigure);
            app.ROInumberCheckBox.ValueChangedFcn = createCallbackFcn(app, @ROInumberCheckBoxValueChanged, true);
            app.ROInumberCheckBox.Text = 'ROI number';
            app.ROIsizeEditFieldLabel = uilabel(app.ViewresultsUIFigure);
            app.ROIsizeEditFieldLabel.Text = 'ROI size';
            app.ROIsizeEditField = uieditfield(app.ViewresultsUIFigure, 'numeric');
            app.ROIsizeEditField.ValueChangedFcn = createCallbackFcn(app, @ROIsizeEditFieldValueChanged, true);
            app.ROIsizeEditField.Value = 10;
            app.TransparencyEditFieldLabel = uilabel(app.ViewresultsUIFigure);
            app.TransparencyEditFieldLabel.Text = 'Transparency';
            app.TransparencyEditField = uieditfield(app.ViewresultsUIFigure, 'numeric');
            app.TransparencyEditField.ValueChangedFcn = createCallbackFcn(app, @TransparencyEditFieldValueChanged, true);
            app.TransparencyEditField.Value = 0.4;
            app.DisplayspatialcomponentCheckBox = uicheckbox(app.ViewresultsUIFigure);
            app.DisplayspatialcomponentCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayspatialcomponentCheckBoxValueChanged, true);
            app.DisplayspatialcomponentCheckBox.Text = 'Display spatial component';
            app.MatchROIsButton = uibutton(app.ViewresultsUIFigure, 'push');
            app.MatchROIsButton.ButtonPushedFcn = createCallbackFcn(app, @MatchROIsButtonPushed, true);
            app.MatchROIsButton.Text = 'Match ROIs';
            app.TemplateNumberSliderLabel = uilabel(app.ViewresultsUIFigure);
            app.TemplateNumberSliderLabel.Text = {'Template'; 'Number'};
            app.TemplateNumberSlider = uislider(app.ViewresultsUIFigure);
            app.TemplateNumberSlider.MajorTicks = [];
            app.TemplateNumberSlider.ValueChangedFcn = createCallbackFcn(app, @TemplateNumberSliderValueChanged, true);
            app.TemplateNumberSlider.MinorTicks = [];
            app.BrightnessMaxSliderLabel = uilabel(app.ViewresultsUIFigure);
            app.BrightnessMaxSliderLabel.Text = 'Brightness Max';
            app.BrightnessMaxSlider = uislider(app.ViewresultsUIFigure);
            app.BrightnessMaxSlider.MajorTicks = [];
            app.BrightnessMaxSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessMaxSliderValueChanged, true);
            app.BrightnessMaxSlider.MinorTicks = [];
            app.BrightnessMinSliderLabel = uilabel(app.ViewresultsUIFigure);
            app.BrightnessMinSliderLabel.Text = 'Brightness Min';
            app.BrightnessMinSlider = uislider(app.ViewresultsUIFigure);
            app.BrightnessMinSlider.MajorTicks = [];
            app.BrightnessMinSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessMinSliderValueChanged, true);
            app.BrightnessMinSlider.MinorTicks = [];
            app.MaxdistEditFieldLabel = uilabel(app.ViewresultsUIFigure);
            app.MaxdistEditFieldLabel.Text = 'Max dist.';
            app.MaxdistEditField = uieditfield(app.ViewresultsUIFigure, 'numeric');
            app.MaxdistEditField.Value = 5;
            app.ShowmindaysEditFieldLabel = uilabel(app.ViewresultsUIFigure);
            app.ShowmindaysEditFieldLabel.Text = 'Show min. days';
            app.ShowmindaysEditField = uieditfield(app.ViewresultsUIFigure, 'numeric');
            app.ShowmindaysEditField.ValueChangedFcn = createCallbackFcn(app, @ShowmindaysEditFieldValueChanged, true);
            app.ShowmaxdaysEditFieldLabel = uilabel(app.ViewresultsUIFigure);
            app.ShowmaxdaysEditFieldLabel.Text = 'Show max. days';
            app.ShowmaxdaysEditField = uieditfield(app.ViewresultsUIFigure, 'numeric');
            app.ShowmaxdaysEditField.ValueChangedFcn = createCallbackFcn(app, @ShowmaxdaysEditFieldValueChanged, true);
            app.MatchedROIdisplaysettingLabel = uilabel(app.ViewresultsUIFigure);
            app.MatchedROIdisplaysettingLabel.Text = 'Matched ROI display setting:';
            app.RegistrationresultLabel = uilabel(app.ViewresultsUIFigure);
            app.RegistrationresultLabel.Text = 'Registration result';
        end
    end

    methods (Access = public)

        % Construct app
        function app = view_multisession_align

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ViewresultsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ViewresultsUIFigure)
        end
    end
end